classdef carDetector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        carDetector_UI     matlab.ui.Figure
        Menu_lis           matlab.ui.container.Menu
        Menu_web           matlab.ui.container.Menu
        menu_goweb         matlab.ui.container.Menu
        Menu_res           matlab.ui.container.Menu
        menu_gores         matlab.ui.container.Menu
        Menu_inf           matlab.ui.container.Menu
        Menu_pro           matlab.ui.container.Menu
        Menu_author        matlab.ui.container.Menu
        CarDetectorMenu    matlab.ui.container.Menu
        Button_pic         matlab.ui.control.Button
        Button_vedio       matlab.ui.control.Button
        Label_vedio        matlab.ui.control.Label
        Label_pic          matlab.ui.control.Label
        Lamp               matlab.ui.control.Lamp
        Panel              matlab.ui.container.Panel
        scoreGauge         matlab.ui.control.SemicircularGauge
        Label_name_detect  matlab.ui.control.Label
        Label_num_detect   matlab.ui.control.Label
        Label              matlab.ui.control.Label
        DropDown           matlab.ui.control.DropDown
        Label_3            matlab.ui.control.Label
        bboxPanel          matlab.ui.container.Panel
        x1Label            matlab.ui.control.Label
        y1Label            matlab.ui.control.Label
        x2Label            matlab.ui.control.Label
        y2Label            matlab.ui.control.Label
        Label_x1           matlab.ui.control.Label
        Label_y1           matlab.ui.control.Label
        Label_x2           matlab.ui.control.Label
        Label_y2           matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        carLabel           matlab.ui.control.Label
        Label_score        matlab.ui.control.Label
        Button_model       matlab.ui.control.Button
        Label_model        matlab.ui.control.Label
        Label_title        matlab.ui.control.Label
        Label_lamp         matlab.ui.control.Label
        Panel_2            matlab.ui.container.Panel
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Det_raw = load("yolov2_mytrain.mat",'detector'); % Description
        %上面这个对象写活，利用参考资料写活该部分，参考资料部分：https://ww2.mathworks.cn/help/vision/object-detection.html?s_tid=CRUX_lftnav
        
        
        Det = [];
        inputSize = [448 448];
        bboxes=[];
        scores=[];
        Image = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function iniGUIFun(app)
            set(app.carDetector_UI, 'visible','off')
            % 将主窗口移动至屏幕中央
            movegui(app.carDetector_UI,"center")
            
            % 显示初始化图片
            %%Im = imread('iniImage.png');
            Im = imread('MyImage2.jpg');
            % Im = imresize(Im, app.UIAxes.Position(3:4));
            image(app.UIAxes, Im);
            axis(app.UIAxes, [0 size(Im, 2) 0 size(Im, 1)]);
            
            set(app.carDetector_UI, 'visible','on')
            app.Det = app.Det_raw;
        end

        % Menu selected function: menu_goweb
        function goToBlog(app, event)
            web('https://www.kuangstudy.com/user/799222')
        end

        % Menu selected function: menu_gores
        function goToResource(app, event)
            web('https://www.kuangstudy.com/user/799222')
        end

        % Menu selected function: Menu_author
        function dispAuthor(app, event)
            handle1 = dialog('WindowStyle','modal','visible','off','Name','关于作者',"Position",[20 20 300 150]);
            movegui(handle1,"center")
            h = uicontrol('Style','text','FontSize',12,'FontName','仿宋', ...
                'position',[0.4 0.4 300 150]);
            string = {'作者：厉彦一','山东科技大学 测绘学院2017级学生',...
                '个人网址：','https://www.kuangstudy.com/user/799222',...
                '擅长技术：','Matlab，C，C++，Java初级，Python初级，Linux等，欢迎交流学习',...
                '邮箱：LiYanyi19981104@163.com'};
            movegui(h,"center")
            set(h,'String',string,'HorizontalAlignment','Left');
            set(handle1, 'visible', 'on');
            set(handle1, 'WindowStyle', 'modal');
            uiwait(handle1); 
        end

        % Menu selected function: Menu_pro
        function dispProject(app, event)
            handle1 = dialog('WindowStyle','modal','visible','off','Name','关于项目',"Position",[20 20 300 150]);
            movegui(handle1,"center")
            h = uicontrol('Style','text','FontSize',12,'FontName','仿宋','position',[0.4 0.4 300 150]);
            string = {'软件名：LYYCD深度学习车辆检测系统','版本：v1.0','时间：2021-05-04',...
                '开发软件：MATLAB R2020b','开发工具：App Designer',...
                '','提示：原理及使用方法请参考厉彦一的Git','https://gitee.com/liyanyi19981104'};
            movegui(h,"center")
            set(h,'String',string,'HorizontalAlignment','Left');
            set(handle1, 'visible','on');
            set(handle1, 'WindowStyle', 'modal');
            uiwait(handle1); 
        end

        % Button pushed function: Button_pic
        function selectImage(app, event)
            % 初始化参数
            %app.inputSize = app.Det.detector.TrainingImageSize; %!!!!
            app.inputSize=[244 244];
            app.bboxes = [];
            app.scores = [];
            % 弹出文件选择框，选择一张图片
            [file,path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp;*.tif',...
                '图片文件 (*.jpg,*.jpeg,*.png,*.bmp,*.tif)'},'选择一张图片');
            
            if isequal(file,0) % 若文件不存在
                set(app.Label_pic,'Text','图片未选中');
            else
                set(app.Label_vedio,'Text','视频未选中');
                
                fileName= fullfile(path,file); % 选择的图片绝对路径
                set(app.Label_pic, 'Text',fileName); % 显示选择的图片路径
                set(app.Lamp, 'Color', [0, 1, 0]);
                set(app.Label_lamp, 'Text', '监测中')         
       
                I = imread(fileName);
%                 I = imresize(I, app.inputSize(1:2));

                app.Image = I;
                % 开始检测
                [app.bboxes,app.scores] = detect(app.Det.detector,I);
%                 scale = size(I,[1 2])./app.inputSize(1:2);
%                 bbox_resize = bboxresize(app.bboxes, scale);
%                 app.bboxes = bbox_resize;
                
                if app.scores
                    I = insertObjectAnnotation(I,'rectangle',app.bboxes,round(app.scores, 2),'LineWidth',2);
                    % I = imresize(I, app.UIAxes.Position(3:4));
                    image(app.UIAxes, I);
                    axis(app.UIAxes, [0 size(I, 2) 0 size(I, 1)]);
                    % 在右侧显示窗口显示详情
                    num_detect = length(app.scores);
                    set(app.Label_num_detect, 'Text', num2str(num_detect))
                    
                    items_detect = cell(1, num_detect+1);
                    items_detect{1} = '所有对象';
                    for i = 2:num_detect+1
                        items_detect{i} = ['对象', num2str(i-1)];
                    end
                    app.DropDown.Items = items_detect;
                    
                    % 显示item1的bbox和score
                    set(app.Label_x1, 'Text', num2str(app.bboxes(1,1)));
                    set(app.Label_y1, 'Text', num2str(app.bboxes(1,2)));
                    set(app.Label_x2, 'Text', num2str(app.bboxes(1,3)));
                    set(app.Label_y2, 'Text', num2str(app.bboxes(1,4)));
                    set(app.Label_score, 'Text', sprintf('%.2f',app.scores(1, 1)));
                    set(app.scoreGauge, 'Value', app.scores(1, 1)*100)
                else
                    set(app.Lamp, 'Color', [1, 0, 0]);
                    set(app.Label_lamp, 'Text', '未检到') 
                end
               
            end
        end

        % Value changed function: DropDown
        function changeDisp(app, event)
            value = app.DropDown.Value;
            % 显示item1的bbox和score
            
            id = str2double(value(3:end));
            if ~isnan(id)
                if ~isempty(app.bboxes)
                    I = insertObjectAnnotation(app.Image,'rectangle',app.bboxes(id, :),round(app.scores(id, 1), 2),'Color','red','LineWidth',2);
                    % I = imresize(I, app.UIAxes.Position(3:4));
                    image(app.UIAxes, I);
                    axis(app.UIAxes, [0 size(I, 2) 0 size(I, 1)]);
                    set(app.Label_x1, 'Text', num2str(app.bboxes(id, 1)));
                    set(app.Label_y1, 'Text', num2str(app.bboxes(id, 2)));
                    set(app.Label_x2, 'Text', num2str(app.bboxes(id, 3)));
                    set(app.Label_y2, 'Text', num2str(app.bboxes(id, 4)));
                    set(app.Label_score, 'Text', sprintf('%.2f',app.scores(id, 1)));
                    set(app.scoreGauge, 'Value', app.scores(id, 1)*100)
                end
            else
                I = insertObjectAnnotation(app.Image,'rectangle',app.bboxes,round(app.scores, 2),'LineWidth',2);
                % I = imresize(I, app.UIAxes.Position(3:4));
                image(app.UIAxes, I);
                axis(app.UIAxes, [0 size(I, 2) 0 size(I, 1)]);
                set(app.Label_x1, 'Text', num2str(app.bboxes(1, 1)));
                set(app.Label_y1, 'Text', num2str(app.bboxes(1, 2)));
                set(app.Label_x2, 'Text', num2str(app.bboxes(1, 3)));
                set(app.Label_y2, 'Text', num2str(app.bboxes(1, 4)));
                set(app.Label_score, 'Text', sprintf('%.2f',app.scores(1, 1)));
                set(app.scoreGauge, 'Value', app.scores(1, 1)*100)
            end
        end

        % Button pushed function: Button_vedio
        function selectVideo(app, event)
            app.bboxes = {};
            app.scores = {};
            %app.inputSize = app.Det.detector.TrainingImageSize; %!!!!
            app.inputSize=[244 244];
             % 弹出文件选择框，选择一张图片
            [file,path] = uigetfile({'*.mp4;*.avi',...
                '视频文件 (*.mp4,*.avi)'},'选择一个视频');
            
            if isequal(file,0) % 若文件不存在
                set(app.Label_vedio,'Text','视频未选中');
            else
                set(app.Label_pic,'Text','图片未选中');
                
                fileName= fullfile(path,file); % 选择的图片绝对路径
                set(app.Label_vedio, 'Text',fileName); % 显示选择的图片路径
                set(app.Lamp, 'Color', [0, 1, 0]);
                set(app.Label_lamp, 'Text', '监测中')
                
                % 读取视频并处理
                v = VideoReader(fileName);
                writer = VideoWriter('transcoded_video.avi');
                open(writer);
                i = 0;
                while hasFrame(v)
                    video = readFrame(v);
                    
                    I = video;
%                     I = imresize(I,app.inputSize(1:2));
                    % 逐帧检测
                    i = i + 1;
                    [app.bboxes{i},app.scores{i}] = detect(app.Det.detector, I);
%                     scale = size(I,[1 2])./app.inputSize(1:2);
%                     bbox_resize = bboxresize(app.bboxes{i}, scale);
%                     app.bboxes{i} = bbox_resize;
                    
                    if app.scores{i}
                        I = insertObjectAnnotation(I,'rectangle',app.bboxes{i},round(app.scores{i}, 2));
                    end
                    
                
                    writeVideo(writer,I);
                    
                end
                close(writer);
                
                % 播放已标记的视频
                v = VideoReader('transcoded_video.avi');
                i = 0;
                while hasFrame(v)
                    video = readFrame(v);
                    image(app.UIAxes, video);
                    axis(app.UIAxes, [0 size(video, 2) 0 size(video, 1)]);
                    
                    % 更新显示
                    i = i + 1;
                    % 显示item1的bbox和score
                    num_detect = length(app.scores{i});
                    set(app.Label_num_detect, 'Text', num2str(num_detect))
                    if app.scores{i}
                        set(app.Label_x1, 'Text', num2str(app.bboxes{i}(1,1)));
                        set(app.Label_y1, 'Text', num2str(app.bboxes{i}(1,2)));
                        set(app.Label_x2, 'Text', num2str(app.bboxes{i}(1,3)));
                        set(app.Label_y2, 'Text', num2str(app.bboxes{i}(1,4)));
                        set(app.Label_score, 'Text', sprintf('%.2f',app.scores{i}(1, 1)));
                        set(app.scoreGauge, 'Value', app.scores{i}(1, 1)*100)
                    end
                        pause(0.15);
                        
                end
            end
        end

        % Button pushed function: Button_model
        function selectModel(app, event)
            % 弹出文件选择框，选择一张图片
            [file,path] = uigetfile({'*.mat',...
                'mat文件 (*.mat)'},'选择您的模型');
            
            if isequal(file,0) % 若文件不存在
                set(app.Label_model,'Text','默认模型');
                app.Det = app.Det_raw;
            else
                fileName= fullfile(path, file); % 选择的图片绝对路径
                set(app.Label_model, 'Text', fileName); % 显示选择的图片路径
                
                app.Det = load(fileName, 'detector');
                %app.inputSize = app.Det.detector.TrainingImageSize; %!!!!
                app.inputSize=[244 244];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.carDetector_UI = uifigure('Visible', 'off');
            app.carDetector_UI.Name = 'LYYCD_V1.0';
            app.carDetector_UI.Icon = 'icon1.png';
            app.Menu_lis = uimenu(app.carDetector_UI);
            app.Menu_lis.Text = '菜单';
            app.Menu_web = uimenu(app.Menu_lis);
            app.Menu_web.Text = '原文链接';
            app.menu_goweb = uimenu(app.Menu_web);
            app.menu_goweb.MenuSelectedFcn = createCallbackFcn(app, @goToBlog, true);
            app.menu_goweb.Text = 'https://www.kuangstudy.com/user/799222';
            app.Menu_res = uimenu(app.Menu_lis);
            app.Menu_res.Text = '资源链接';
            app.menu_gores = uimenu(app.Menu_res);
            app.menu_gores.MenuSelectedFcn = createCallbackFcn(app, @goToResource, true);
            app.menu_gores.Text = 'https://www.kuangstudy.com/user/799222';
            app.Menu_inf = uimenu(app.carDetector_UI);
            app.Menu_inf.Text = '信息';
            app.Menu_pro = uimenu(app.Menu_inf);
            app.Menu_pro.MenuSelectedFcn = createCallbackFcn(app, @dispProject, true);
            app.Menu_pro.Text = '项目简介';
            app.Menu_author = uimenu(app.Menu_inf);
            app.Menu_author.MenuSelectedFcn = createCallbackFcn(app, @dispAuthor, true);
            app.Menu_author.Text = '作者信息';
            app.CarDetectorMenu = uimenu(app.carDetector_UI);
            app.CarDetectorMenu.Enable = 'off';
            app.CarDetectorMenu.Text = '  LYYCarDetect v1.0, Author：厉彦一, Website：https://www.kuangstudy.com/user/799222t';
            app.Button_pic = uibutton(app.carDetector_UI, 'push');
            app.Button_pic.ButtonPushedFcn = createCallbackFcn(app, @selectImage, true);
            app.Button_pic.Icon = 'pictures.png';
            app.Button_pic.Tooltip = {'选择一张待检测的图片'};
            app.Button_pic.Text = '';
            app.Button_vedio = uibutton(app.carDetector_UI, 'push');
            app.Button_vedio.ButtonPushedFcn = createCallbackFcn(app, @selectVideo, true);
            app.Button_vedio.Icon = 'video.png';
            app.Button_vedio.Tooltip = {'选择一个视频文件'};
            app.Button_vedio.Text = '';
            app.Label_vedio = uilabel(app.carDetector_UI);
            app.Label_vedio.Text = '视频未选中';
            app.Label_pic = uilabel(app.carDetector_UI);
            app.Label_pic.Text = '图片未选中';
            app.Lamp = uilamp(app.carDetector_UI);
            app.Panel = uipanel(app.carDetector_UI);
            app.Panel.Title = '结果';
            app.scoreGauge = uigauge(app.Panel, 'semicircular');
            app.Label_name_detect = uilabel(app.Panel);
            app.Label_name_detect.Text = '检测到的对象个数：';
            app.Label_num_detect = uilabel(app.Panel);
            app.Label_num_detect.Text = '0';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '选择对象：';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'所有对象'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @changeDisp, true);
            app.DropDown.Value = '所有对象';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '置信分数:';
            app.bboxPanel = uipanel(app.Panel);
            app.bboxPanel.Title = 'bbox';
            app.x1Label = uilabel(app.bboxPanel);
            app.x1Label.Text = 'x1:';
            app.y1Label = uilabel(app.bboxPanel);
            app.y1Label.Text = 'y1:';
            app.x2Label = uilabel(app.bboxPanel);
            app.x2Label.Text = 'x2:';
            app.y2Label = uilabel(app.bboxPanel);
            app.y2Label.Text = 'y2:';
            app.Label_x1 = uilabel(app.bboxPanel);
            app.Label_x1.Text = '0';
            app.Label_y1 = uilabel(app.bboxPanel);
            app.Label_y1.Text = '0';
            app.Label_x2 = uilabel(app.bboxPanel);
            app.Label_x2.Text = '0';
            app.Label_y2 = uilabel(app.bboxPanel);
            app.Label_y2.Text = '0';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '分类类别:';
            app.carLabel = uilabel(app.Panel);
            app.carLabel.Text = 'car';
            app.Label_score = uilabel(app.Panel);
            app.Label_score.Text = '0';
            app.Button_model = uibutton(app.carDetector_UI, 'push');
            app.Button_model.ButtonPushedFcn = createCallbackFcn(app, @selectModel, true);
            app.Button_model.Icon = 'models.png';
            app.Button_model.Tooltip = {'选择模型文件，默认原始'};
            app.Button_model.Text = '';
            app.Label_model = uilabel(app.carDetector_UI);
            app.Label_model.Text = '默认模型';
            app.Label_title = uilabel(app.carDetector_UI);
            app.Label_title.Text = 'LYYCD深度学习车辆检测系统';
            app.Label_lamp = uilabel(app.carDetector_UI);
            app.Label_lamp.Text = '未就绪';
            app.Panel_2 = uipanel(app.carDetector_UI);
            app.Panel_2.Title = '显示';
            app.UIAxes = uiaxes(app.Panel_2);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            % Show the figure after all components are created
            app.carDetector_UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = carDetector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.carDetector_UI)

            % Execute the startup function
            runStartupFcn(app, @iniGUIFun)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.carDetector_UI)
        end
    end
end